classdef Draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UsarimagenesdemuestraButton    matlab.ui.control.Button
        LascategoriasquedeberaclasificarsonLabel  matlab.ui.control.Label
        SiguienteimagenButton          matlab.ui.control.Button
        CategoriapredichaLabel         matlab.ui.control.Label
        ClasificarimagenButton         matlab.ui.control.Button
        AppparaclasificarimgenesLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SeleccionatusarchivosButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        pth % Description
        file_names
        Cats_chosen
        imds_prueba
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load Cats_chosen.mat Cats_chosen;
            app.Cats_chosen = Cats_chosen;
            str = "Las categorias que debería clasificar son: ";

            for i = 1:numel(app.Cats_chosen)
                if i == numel(app.Cats_chosen)-1
                    str = strcat(str,app.Cats_chosen(i),", y ");
                elseif i == numel(app.Cats_chosen)
                    str = strcat(str,app.Cats_chosen(i));
                else
                    str = strcat(str,app.Cats_chosen(i),", ");
                end
            end            
            app.LascategoriasquedeberaclasificarsonLabel.Text = str;
            
            app.Image.Visible = "off";
            app.SiguienteimagenButton.Visible= "off";
            app.ClasificarimagenButton.Visible = "off";
            app.CategoriapredichaLabel.Visible = "off";
        end

        % Button pushed function: SeleccionatusarchivosButton
        function SeleccionatusarchivosButtonPushed(app, event)
            if app.SeleccionatusarchivosButton.Text ~= "Reiniciar todo"
                [files,app.pth] = uigetfile("MultiSelect","on");
                files = string(files);
                app.file_names = fullfile(app.pth,files);
                app.Image.ImageSource = app.file_names(1);
                app.Image.Visible = "on";
                app.SeleccionatusarchivosButton.Text = "Reiniciar todo";
                app.ClasificarimagenButton.Visible = "on";
                if numel(app.file_names) > 1
                    app.SiguienteimagenButton.Visible= "on";
                end
                app.UsarimagenesdemuestraButton.Visible = "off";
            else
                app.Image.Visible = "off";
                app.SiguienteimagenButton.Visible= "off";
                app.ClasificarimagenButton.Visible = "off";
                app.CategoriapredichaLabel.Visible = "off";
                app.SeleccionatusarchivosButton.Text = "Selecciona tus archivos";
                app.UsarimagenesdemuestraButton.Visible = "on";

            end
        end

        % Button pushed function: SiguienteimagenButton
        function SiguienteimagenButtonPushed(app, event)
            if numel(app.file_names)>=1
                idx = find(app.Image.ImageSource == app.file_names);
                if idx == numel(app.file_names)
                    app.Image.ImageSource = app.file_names(1);
                else
                    app.Image.ImageSource = app.file_names(idx+1);
                end
                app.CategoriapredichaLabel.Visible = "off";
            else
                app.Image.ImageSource = string(app.imds_prueba.Files(randi(numel(app.imds_prueba.Files))));
                app.SeleccionatusarchivosButton.Text = "Reiniciar todo";
                app.Image.Visible = "on";
                app.ClasificarimagenButton.Visible = "on";
                app.SiguienteimagenButton.Visible= "on";
                app.CategoriapredichaLabel.Visible = "off";
            end
        end

        % Button pushed function: ClasificarimagenButton
        function ClasificarimagenButtonPushed(app, event)
            load Red_entrenada_4_cats.mat Red_entrenada
            imd = readAndResizeImages(app.Image.ImageSource);
            data = predict(Red_entrenada,imd);
            if any(data > 0.9)
                idx = find(data > 0.9);
                app.CategoriapredichaLabel.Text = strcat("La app predice que la imagen corresponde a ",app.Cats_chosen(idx));
                app.CategoriapredichaLabel.Visible = "on";
            else
                app.CategoriapredichaLabel.Text = strcat("La app no está segura de cómo clasificar esta imagen");
                app.CategoriapredichaLabel.Visible = "on";
            end
        end

        % Button pushed function: UsarimagenesdemuestraButton
        function UsarimagenesdemuestraButtonPushed(app, event)
            app.imds_prueba = imageDatastore("Testing_images\","IncludeSubfolders",true);
            app.Image.ImageSource = string(app.imds_prueba.Files(randi(numel(app.imds_prueba.Files))));
            app.SeleccionatusarchivosButton.Text = "Reiniciar todo";
            app.Image.Visible = "on";
            app.ClasificarimagenButton.Visible = "on";
            app.SiguienteimagenButton.Visible= "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeleccionatusarchivosButton = uibutton(app.UIFigure, 'push');
            app.SeleccionatusarchivosButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionatusarchivosButtonPushed, true);
            app.SeleccionatusarchivosButton.Text = 'Selecciona tus archivos';
            app.Image = uiimage(app.UIFigure);
            app.AppparaclasificarimgenesLabel = uilabel(app.UIFigure);
            app.AppparaclasificarimgenesLabel.Text = 'App para clasificar imágenes';
            app.ClasificarimagenButton = uibutton(app.UIFigure, 'push');
            app.ClasificarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarimagenButtonPushed, true);
            app.ClasificarimagenButton.Text = 'Clasificar imagen';
            app.CategoriapredichaLabel = uilabel(app.UIFigure);
            app.CategoriapredichaLabel.Text = 'Categoria predicha';
            app.SiguienteimagenButton = uibutton(app.UIFigure, 'push');
            app.SiguienteimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteimagenButtonPushed, true);
            app.SiguienteimagenButton.Text = 'Siguiente imagen';
            app.LascategoriasquedeberaclasificarsonLabel = uilabel(app.UIFigure);
            app.LascategoriasquedeberaclasificarsonLabel.Text = 'Las categorias que debería clasificar son:';
            app.UsarimagenesdemuestraButton = uibutton(app.UIFigure, 'push');
            app.UsarimagenesdemuestraButton.ButtonPushedFcn = createCallbackFcn(app, @UsarimagenesdemuestraButtonPushed, true);
            app.UsarimagenesdemuestraButton.Text = 'Usar imagenes de muestra';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end